classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NeuralTab                      matlab.ui.container.Tab
        DesenharButton                 matlab.ui.control.Button
        NomedaRedeEditField            matlab.ui.control.EditField
        NomedaRedeEditFieldLabel       matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        ClassificarComDatasetTab       matlab.ui.container.Tab
        ClassificarcomumDatasetPanel   matlab.ui.container.Panel
        DatasetEscolherRede            matlab.ui.control.DropDown
        RedeDropDownLabel_2            matlab.ui.control.Label
        SimularButton                  matlab.ui.control.Button
        ResultadoPanel                 matlab.ui.container.Panel
        DatasetResultado               matlab.ui.control.Label
        DatasetEscolherPasta           matlab.ui.control.DropDown
        PastaLabel_2                   matlab.ui.control.Label
        ClassificarComFicheiroTab      matlab.ui.container.Tab
        ClassificarComumFicheiroPanel  matlab.ui.container.Panel
        ProcurarButton                 matlab.ui.control.Button
        FicheiroEscolherRede           matlab.ui.control.DropDown
        RedeDropDownLabel              matlab.ui.control.Label
        NomeSimularFicheiro            matlab.ui.control.EditField
        FicheiroLabel                  matlab.ui.control.Label
        SimularButton_2                matlab.ui.control.Button
        ResultadoPanel_2               matlab.ui.container.Panel
        ResultadoSimularFicheiro       matlab.ui.control.Label
        CriareTreinarRedeButton        matlab.ui.control.Button
        ResultadoPanel_3               matlab.ui.container.Panel
        AccuracyCreateEditField        matlab.ui.control.NumericEditField
        ConfiguraoPanel                matlab.ui.container.Panel
        TestEditField                  matlab.ui.control.NumericEditField
        TestEditFieldLabel             matlab.ui.control.Label
        ValidateEditField              matlab.ui.control.NumericEditField
        ValidateEditFieldLabel         matlab.ui.control.Label
        TreinoEditField                matlab.ui.control.NumericEditField
        TreinoEditFieldLabel           matlab.ui.control.Label
        CamadasPanel                   matlab.ui.container.Panel
        TopologiaEditField             matlab.ui.control.EditField
        DivisonDropDown                matlab.ui.control.DropDown
        DivisoDropDownLabel            matlab.ui.control.Label
        AtivaoOutputDropDown           matlab.ui.control.DropDown
        AtivaoOutputLabel              matlab.ui.control.Label
        TreinoDropDown                 matlab.ui.control.DropDown
        TreinoDropDownLabel            matlab.ui.control.Label
        AtivaoEscondidaDropDown        matlab.ui.control.DropDown
        AtivaoEscondidaLabel           matlab.ui.control.Label
        InputsPanel                    matlab.ui.container.Panel
        EpochsSlider                   matlab.ui.control.Slider
        EpochsSliderLabel              matlab.ui.control.Label
        FolderSelectorDropDown         matlab.ui.control.DropDown
        PastaLabel                     matlab.ui.control.Label
        RealizaoTab                    matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = dir('Trabalho\Out\Redes\App\*.mat');
            
            app.FicheiroEscolherRede.Items = {list.name};
            app.DatasetEscolherRede.Items = {list.name};
        end

        % Button pushed function: CriareTreinarRedeButton
        function CriareTreinarRedeButtonPushed(app, event)
            [matrizBinaria, targetMatrix] = tratamentoDeImagem(app.FolderSelectorDropDown.Value);

            epochsValue = app.EpochsSlider.Value;

            [topologia,flag] = str2num(app.TopologiaEditField.Value,"Evaluation","restricted");
            if ~flag || numel(topologia) == 0 || any(topologia(:) == 0)
                errordlg("Topologia não pode ser nula");
                return;
            end

            trainFuction = app.TreinoDropDown.Value;
            fuctionActivationHidden = app.AtivaoEscondidaDropDown.Value;
            fuctionActivationOutput = app.AtivaoOutputDropDown.Value;
            fuctionDivision = app.DivisonDropDown.Value;
            trainValue = app.TreinoEditField.Value;
            validateValue = app.ValidateEditField.Value;
            testValue = app.TestEditField.Value;
            nomeRede = app.NomedaRedeEditField.Value;
            
            [accuracy_global] = treinamentoDeRede(topologia,matrizBinaria,targetMatrix,epochsValue,trainFuction,fuctionActivationHidden, ...
                                                  fuctionActivationOutput,fuctionDivision,trainValue,validateValue,testValue,nomeRede);
            
            app.AccuracyCreateEditField.Value = accuracy_global;
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            if app.DatasetEscolherPasta.Value ~= "" && app.DatasetEscolherRede.Value ~= ""
                app.DatasetResultado.Text = sprintf("Accuracy = %f",carregarRede(app.DatasetEscolherRede.Value , app.DatasetEscolherPasta.Value));
            end
        end

        % Button pushed function: SimularButton_2
        function SimularButton_2Pushed(app, event)
             if app.NomeSimularFicheiro.Value ~= "" && app.FicheiroEscolherRede.Value ~= ""
                app.ResultadoSimularFicheiro.Text = sprintf("Imagem Identificada = %s",identificarImagem(app.FicheiroEscolherRede.Value , app.NomeSimularFicheiro.Value , 0));
            end
        end

        % Button pushed function: DesenharButton
        function DesenharButtonPushed(app, event)
            desenho;
        end

        % Button pushed function: ProcurarButton
        function ProcurarButtonPushed(app, event)
          
            [ficheiroNome,caminhoNome] = uigetfile('Trabalho\Imagens\;*.png');

            caminhoAlterado = extractAfter(caminhoNome , "Imagens\");
            caminhoFinal = strcat(caminhoAlterado , ficheiroNome);
            
            app.NomeSimularFicheiro.Value = caminhoFinal;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NeuralTab = uitab(app.TabGroup);
            app.NeuralTab.Title = 'Neural';
            app.InputsPanel = uipanel(app.NeuralTab);
            app.InputsPanel.Title = 'Inputs';
            app.PastaLabel = uilabel(app.InputsPanel);
            app.PastaLabel.Text = 'Pasta';
            app.FolderSelectorDropDown = uidropdown(app.InputsPanel);
            app.FolderSelectorDropDown.Items = {'start', 'test', 'train', 'extra'};
            app.FolderSelectorDropDown.Value = 'start';
            app.EpochsSliderLabel = uilabel(app.InputsPanel);
            app.EpochsSliderLabel.Text = 'Epochs';
            app.EpochsSlider = uislider(app.InputsPanel);
            app.EpochsSlider.Limits = [1 1000];
            app.EpochsSlider.MajorTicks = [1 200 400 600 800 1000];
            app.EpochsSlider.MinorTicks = [100 300 500 700 900];
            app.EpochsSlider.Value = 1;
            app.CamadasPanel = uipanel(app.NeuralTab);
            app.CamadasPanel.Title = 'Camadas';
            app.AtivaoEscondidaLabel = uilabel(app.CamadasPanel);
            app.AtivaoEscondidaLabel.Text = {'Ativação'; 'Escondida'};
            app.AtivaoEscondidaDropDown = uidropdown(app.CamadasPanel);
            app.AtivaoEscondidaDropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.AtivaoEscondidaDropDown.Value = 'tansig';
            app.TreinoDropDownLabel = uilabel(app.CamadasPanel);
            app.TreinoDropDownLabel.Text = 'Treino';
            app.TreinoDropDown = uidropdown(app.CamadasPanel);
            app.TreinoDropDown.Items = {'traingd', 'trainlm', 'traingdm', 'trainlm', 'trainbfg', 'trainbr'};
            app.TreinoDropDown.Value = 'traingd';
            app.AtivaoOutputLabel = uilabel(app.CamadasPanel);
            app.AtivaoOutputLabel.Text = {'Ativação'; 'Output'};
            app.AtivaoOutputDropDown = uidropdown(app.CamadasPanel);
            app.AtivaoOutputDropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.AtivaoOutputDropDown.Value = 'tansig';
            app.DivisoDropDownLabel = uilabel(app.CamadasPanel);
            app.DivisoDropDownLabel.Text = 'Divisão';
            app.DivisonDropDown = uidropdown(app.CamadasPanel);
            app.DivisonDropDown.Items = {'', 'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.DivisonDropDown.Value = 'dividerand';
            app.TopologiaEditField = uieditfield(app.CamadasPanel, 'text');
            app.ConfiguraoPanel = uipanel(app.NeuralTab);
            app.ConfiguraoPanel.Title = 'Configuração';
            app.TreinoEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.ConfiguraoPanel, 'numeric');
            app.TreinoEditField.Limits = [0 Inf];
            app.TreinoEditField.Value = 0.7;
            app.ValidateEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.ValidateEditFieldLabel.Text = 'Validate';
            app.ValidateEditField = uieditfield(app.ConfiguraoPanel, 'numeric');
            app.ValidateEditField.Value = 0.15;
            app.TestEditFieldLabel = uilabel(app.ConfiguraoPanel);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.ConfiguraoPanel, 'numeric');
            app.TestEditField.Value = 0.15;
            app.ResultadoPanel_3 = uipanel(app.NeuralTab);
            app.ResultadoPanel_3.Title = 'Resultado';
            app.AccuracyCreateEditField = uieditfield(app.ResultadoPanel_3, 'numeric');
            app.AccuracyCreateEditField.Editable = 'off';
            app.CriareTreinarRedeButton = uibutton(app.NeuralTab, 'push');
            app.CriareTreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriareTreinarRedeButtonPushed, true);
            app.CriareTreinarRedeButton.Text = 'Criar e Treinar Rede';
            app.TabGroup2 = uitabgroup(app.NeuralTab);
            app.ClassificarComDatasetTab = uitab(app.TabGroup2);
            app.ClassificarComDatasetTab.Title = 'Classificar Com Dataset';
            app.ClassificarcomumDatasetPanel = uipanel(app.ClassificarComDatasetTab);
            app.ClassificarcomumDatasetPanel.Title = 'Classificar com um Dataset';
            app.PastaLabel_2 = uilabel(app.ClassificarcomumDatasetPanel);
            app.PastaLabel_2.Text = 'Pasta ';
            app.DatasetEscolherPasta = uidropdown(app.ClassificarcomumDatasetPanel);
            app.DatasetEscolherPasta.Items = {'start', 'test', 'train'};
            app.DatasetEscolherPasta.Value = 'start';
            app.ResultadoPanel = uipanel(app.ClassificarcomumDatasetPanel);
            app.ResultadoPanel.Title = 'Resultado';
            app.DatasetResultado = uilabel(app.ResultadoPanel);
            app.DatasetResultado.Text = '';
            app.SimularButton = uibutton(app.ClassificarcomumDatasetPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.RedeDropDownLabel_2 = uilabel(app.ClassificarcomumDatasetPanel);
            app.RedeDropDownLabel_2.Text = 'Rede';
            app.DatasetEscolherRede = uidropdown(app.ClassificarcomumDatasetPanel);
            app.DatasetEscolherRede.Items = {};
            app.DatasetEscolherRede.Value = {};
            app.ClassificarComFicheiroTab = uitab(app.TabGroup2);
            app.ClassificarComFicheiroTab.Title = 'Classificar Com Ficheiro';
            app.ClassificarComumFicheiroPanel = uipanel(app.ClassificarComFicheiroTab);
            app.ClassificarComumFicheiroPanel.Title = 'Classificar Com um Ficheiro';
            app.ResultadoPanel_2 = uipanel(app.ClassificarComumFicheiroPanel);
            app.ResultadoPanel_2.Title = 'Resultado';
            app.ResultadoSimularFicheiro = uilabel(app.ResultadoPanel_2);
            app.ResultadoSimularFicheiro.Text = '';
            app.SimularButton_2 = uibutton(app.ClassificarComumFicheiroPanel, 'push');
            app.SimularButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimularButton_2Pushed, true);
            app.SimularButton_2.Text = 'Simular';
            app.FicheiroLabel = uilabel(app.ClassificarComumFicheiroPanel);
            app.FicheiroLabel.Text = 'Ficheiro';
            app.NomeSimularFicheiro = uieditfield(app.ClassificarComumFicheiroPanel, 'text');
            app.RedeDropDownLabel = uilabel(app.ClassificarComumFicheiroPanel);
            app.RedeDropDownLabel.Text = 'Rede';
            app.FicheiroEscolherRede = uidropdown(app.ClassificarComumFicheiroPanel);
            app.FicheiroEscolherRede.Items = {};
            app.FicheiroEscolherRede.Value = {};
            app.ProcurarButton = uibutton(app.ClassificarComumFicheiroPanel, 'push');
            app.ProcurarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.ProcurarButton.Text = 'Procurar';
            app.NomedaRedeEditFieldLabel = uilabel(app.NeuralTab);
            app.NomedaRedeEditFieldLabel.Text = 'Nome da Rede';
            app.NomedaRedeEditField = uieditfield(app.NeuralTab, 'text');
            app.DesenharButton = uibutton(app.NeuralTab, 'push');
            app.DesenharButton.ButtonPushedFcn = createCallbackFcn(app, @DesenharButtonPushed, true);
            app.DesenharButton.Text = 'Desenhar';
            app.RealizaoTab = uitab(app.TabGroup);
            app.RealizaoTab.Title = 'Realização';
            app.Image = uiimage(app.RealizaoTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end