classdef desenho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesenhoUIFigure    matlab.ui.Figure
        EscolherRede       matlab.ui.control.DropDown
        EscolherRedeLabel  matlab.ui.control.Label
        ResetButton        matlab.ui.control.Button
        ResultadoPanel     matlab.ui.container.Panel
        Resultado          matlab.ui.control.Label
        ClassificarButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        grayImage = ones(224,224);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = dir('Trabalho\Out\Redes\App\*.mat');
            
            app.EscolherRede.Items = {list.name};
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            hFH = drawfreehand(app.UIAxes);

            % Get the xy coordinates of where they drew.
            xy = hFH.Position;
            % get rid of imfreehand remnant.
            delete(hFH);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'ro-', 'LineWidth', 2);

            promptMessage = sprintf('Deseja utilizar esta imagem?');
            titleBarCaption = 'Continuar';
            button = questdlg(promptMessage, titleBarCaption, 'OK', 'Não', 'OK');
            if strcmpi(button, 'Não')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                app.ClassificarButton.Enable = false;
                app.ClassificarButton.BackgroundColor = [1.00,0.45,0.45];
                return;
            end
            app.ClassificarButton.Enable = true;
            app.ClassificarButton.BackgroundColor = [0.24,0.82,0.19];

            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                app.grayImage(row, column) = 0;
            end
           
            app.grayImage = flipud(app.grayImage);
            %imshow(app.grayImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            if(app.EscolherRede.Value ~= "")


                app.Resultado.Text = sprintf("Imagem Identificada = %s",identificarImagem(app.EscolherRede.Value , "null" , app.grayImage));
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.Resultado.Text = "";
            app.ClassificarButton.Enable = false;
            app.ClassificarButton.BackgroundColor = [1.00,0.45,0.45];
        end

        % Value changed function: EscolherRede
        function EscolherRedeValueChanged(app, event)
            value = app.EscolherRede.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesenhoUIFigure = uifigure('Visible', 'off');
            app.DesenhoUIFigure.Name = 'Desenho';
            app.UIAxes = uiaxes(app.DesenhoUIFigure);
            title(app.UIAxes, 'Clique e arraste com o botão esquerdo do rato para desenhar')
            app.UIAxes.XLim = [0 224];
            app.UIAxes.YLim = [0 224];
            app.UIAxes.ZLim = [0 224];
            app.UIAxes.XTick = 0.224;
            app.UIAxes.YTick = 0.224;
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ClassificarButton = uibutton(app.DesenhoUIFigure, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Enable = 'off';
            app.ClassificarButton.Text = 'Classificar';
            app.ResultadoPanel = uipanel(app.DesenhoUIFigure);
            app.ResultadoPanel.Title = 'Resultado';
            app.Resultado = uilabel(app.ResultadoPanel);
            app.Resultado.Text = '';
            app.ResetButton = uibutton(app.DesenhoUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EscolherRedeLabel = uilabel(app.DesenhoUIFigure);
            app.EscolherRedeLabel.Text = 'Escolher Rede';
            app.EscolherRede = uidropdown(app.DesenhoUIFigure);
            app.EscolherRede.Items = {''};
            app.EscolherRede.ValueChangedFcn = createCallbackFcn(app, @EscolherRedeValueChanged, true);
            app.EscolherRede.Value = '';
            % Show the figure after all components are created
            app.DesenhoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = desenho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesenhoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesenhoUIFigure)
        end
    end
end